classdef EX2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Menu_read         matlab.ui.container.Menu
        Menu_save         matlab.ui.container.Menu
        Menu_process      matlab.ui.container.Menu
        Menu              matlab.ui.container.Menu
        Menu_2            matlab.ui.container.Menu
        Menu_9            matlab.ui.container.Menu
        Menu_10           matlab.ui.container.Menu
        Menu_11           matlab.ui.container.Menu
        Menu_3            matlab.ui.container.Menu
        Menu_4            matlab.ui.container.Menu
        Menu_5            matlab.ui.container.Menu
        Menu_6            matlab.ui.container.Menu
        Menu_7            matlab.ui.container.Menu
        Menu_8            matlab.ui.container.Menu
        Menu_12           matlab.ui.container.Menu
        Menu_13           matlab.ui.container.Menu
        Menu_16           matlab.ui.container.Menu
        Menu_17           matlab.ui.container.Menu
        Menu_14           matlab.ui.container.Menu
        Menu_18           matlab.ui.container.Menu
        Menu_25           matlab.ui.container.Menu
        Menu_19           matlab.ui.container.Menu
        Menu_20           matlab.ui.container.Menu
        Menu_21           matlab.ui.container.Menu
        Menu_22           matlab.ui.container.Menu
        Menu_23           matlab.ui.container.Menu
        Menu_24           matlab.ui.container.Menu
        Menu_26           matlab.ui.container.Menu
        Menu_28           matlab.ui.container.Menu
        Menu_29           matlab.ui.container.Menu
        Menu_27           matlab.ui.container.Menu
        Menu_30           matlab.ui.container.Menu
        Menu_31           matlab.ui.container.Menu
        Menu_32           matlab.ui.container.Menu
        Menu_33           matlab.ui.container.Menu
        Menu_34           matlab.ui.container.Menu
        Menu_35           matlab.ui.container.Menu
        Menu_36           matlab.ui.container.Menu
        Menu_37           matlab.ui.container.Menu
        imhisMenu         matlab.ui.container.Menu
        barMenu           matlab.ui.container.Menu
        stemMenu          matlab.ui.container.Menu
        Menu_38           matlab.ui.container.Menu
        RGBMenu           matlab.ui.container.Menu
        HSVMenu           matlab.ui.container.Menu
        adaptMenu         matlab.ui.container.Menu
        Menu_40           matlab.ui.container.Menu
        AddNoise          matlab.ui.container.Menu
        PepperNoise       matlab.ui.container.Menu
        GaussianNoise     matlab.ui.container.Menu
        Menu_41           matlab.ui.container.Menu
        Menu_42           matlab.ui.container.Menu
        Menu_43           matlab.ui.container.Menu
        PaddingMenu       matlab.ui.container.Menu
        Menu_45           matlab.ui.container.Menu
        Menu_47           matlab.ui.container.Menu
        Menu_48           matlab.ui.container.Menu
        Menu_49           matlab.ui.container.Menu
        Menu_50           matlab.ui.container.Menu
        Menu_46           matlab.ui.container.Menu
        Menu_51           matlab.ui.container.Menu
        Menu_52           matlab.ui.container.Menu
        Menu_53           matlab.ui.container.Menu
        Menu_54           matlab.ui.container.Menu
        Menu_55           matlab.ui.container.Menu
        Menu_56           matlab.ui.container.Menu
        Menu_57           matlab.ui.container.Menu
        Menu_58           matlab.ui.container.Menu
        Menu_60           matlab.ui.container.Menu
        Menu_61           matlab.ui.container.Menu
        Menu_62           matlab.ui.container.Menu
        Menu_63           matlab.ui.container.Menu
        LaplaceMenu       matlab.ui.container.Menu
        Menu_64           matlab.ui.container.Menu
        Menu_65           matlab.ui.container.Menu
        fspecialMenu      matlab.ui.container.Menu
        SobelMenu         matlab.ui.container.Menu
        PrewittMenu       matlab.ui.container.Menu
        RobertsMenu       matlab.ui.container.Menu
        GradMenu          matlab.ui.container.Menu
        UIAxes            matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Button            matlab.ui.control.Button
        Label_3           matlab.ui.control.Label
        TimeUse           matlab.ui.control.NumericEditField
        Spinner           matlab.ui.control.Spinner
        Label_angle_size  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global im;
            app.Spinner.Limits = [-360, 360];
            app.Label_angle_size.Text = "处理";
        end

        % Menu selected function: Menu_read
        function Menu_readSelected(app, event)
            global im;
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'},'选择一个图片','D:');
            
            if isequal(filename, 0) || isequal(pathname, 0)
                im = 0;
                errordlg("没有选中文件", "错误");
                return;
            else
                file = [pathname, filename];
            end
            im = imread(file);
            imshow(im, 'Parent', app.UIAxes);
        end

        % Menu selected function: Menu_process
        function Menu_processSelected(app, event)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.Label_2.Text = '处理';
        end

        % Menu selected function: Menu_save
        function Menu_saveSelected(app, event)
            [filename,pathname] = uiputfile({'*.jpg','JPEG(*.jpg)';...
                                 '*.bmp','Bitmap(*.bmp)';...
                                 '*.gif','GIF(*.gif)';...
                                 '*.*',  'All Files (*.*)'},...
                                 'Save Picture','Untitled');
             if pathname == 0
                 errordlg("没有保存路径！", "错误");
                 return;
             else
                 h = getframe(app.UIAxes2);
                 imwrite(h.cdata, [pathname, filename]);
             end
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                gray_img = rgb2gray(im);
                imshow(gray_img, 'Parent', app.UIAxes2);
                t2=cputime;
            end
            app.Label_2.Text = '灰度处理';
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            
        end

        % Menu selected function: Menu_9
        function Menu_9Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                [Height, Width] = size(im);
                T1 = affine2d([-1 0 0; 0 1 0;Width 0 1]);
                A1 = imwarp(im, T1);
                imshow(A1,'Parent', app.UIAxes2);
                t2=cputime;
            end
            app.Label_2.Text = '水平镜像';
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                [Height, Width] = size(im);
                T2 = affine2d([1 0 0;0 -1 0;0 Height 1]);
                A2 = imwarp(im, T2);
                imshow(A2,'Parent', app.UIAxes2);
                t2=cputime;
            end
            app.Label_2.Text = '垂直镜像';
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                [Height, Width] = size(im);
                T3 = affine2d([-1 0 0;0 -1 0;Width Height 1]);
                A3 = imwarp(im, T3);
                imshow(A3,'Parent', app.UIAxes2);
                t2=cputime;
            end
            app.Label_2.Text = '对角镜像';
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            app.Spinner.Visible = true;
            app.Label_angle_size.Visible = true;
            app.Label_angle_size.Text = "角度";
            angle = double(app.Spinner.Value);
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                J = imrotate(im, angle, 'nearest', 'crop');
                t2 = cputime;
                imshow(J,'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "最邻近旋转" + num2str(angle,3) + "度";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            app.Label_angle_size.Text = "角度";
            angle = double(app.Spinner.Value);
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                J = imrotate(im, angle, 'bilinear', 'crop');
                t2 = cputime;
                imshow(J,'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "双线性旋转" + num2str(angle,3) + "度";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            app.Label_angle_size.Text = "角度";
            angle = double(app.Spinner.Value);
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                J = imrotate(im, angle, 'bicubic', 'crop');
                t2 = cputime;
                imshow(J,'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "双立方旋转" + num2str(angle,3) + "度";
            t=t2-t1;
            app.TimeUse.Value = t;      
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                [J,~] = imcrop(im);
                t2 = cputime;
                imshow(J,'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "剪切图像";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                app.Label_angle_size.Text = "大小";
                size_pic = double(app.Spinner.Value);
                if size_pic == 0
                    size_pic = 1;
                end
                tform = affine2d([size_pic 0 0; 0 size_pic 0; 0 0 1]);
                t1=cputime;
                J = imwarp(im,tform,'OutputView',imref2d(size(im)));%图像放大缩小
                t2 = cputime;
                imshow(J, 'Parent', app.UIAxes2);
            end
            app.UIAxes.Visible = true;
            app.UIAxes2.Visible = true;
            app.Label_2.Text = "缩放图像";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
         
        end

        % Callback function
        function Menu_13Selected(app, event)
            
        end

        % Menu selected function: Menu_16
        function Menu_16Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            app.Label_angle_size.Text = "角度";
            angle = double(app.Spinner.Value);
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                tform = affine2d([1 tan(angle) 0; 0 1 0; 0 0 1]);
                t1 = cputime;
                J = imwarp(im,tform);
                t2 = cputime;
                imshow(J, 'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "垂直错切图像";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_17
        function Menu_17Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            app.Label_angle_size.Text = "角度";
            angle = double(app.Spinner.Value);
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1 = cputime;
                tform = affine2d([1 0 0; tan(angle) 1 0; 0 0 1]);
                J = imwarp(im,tform);
                t2 = cputime;
                imshow(J, 'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "水平错切图像";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_14
        function Menu_14Selected(app, event)
            global im;
            app.Label_angle_size.Text = "X";
            app.Label_angle_size_2.Text = "Y";
            app.Label_angle_size_2.Visible = true;
            app.Spinner_2.Visible = true;
            app.UIAxes2.Visible = true;
            Tx = double(app.Spinner.Value);
            Ty = double(app.Spinner.Value);
            im_ref = imref2d(size(im));
            % 限制坐标轴不动
            im_translated_ref = im_ref;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                tform =affine2d([1 0 0 ; 0 1 0 ; Tx Ty 1 ]);
                t1 = cputime;
                J = imwarp(im,tform,'OutputView',im_translated_ref);%图像平移
                t2 = cputime;
                imshow(J, 'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "平移变换图像";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_18
        function Menu_18Selected(app, event)
            app.Label_angle_size.Text = "亮度大小";
            app.Label_2.Text = "被处理图";
        end

        % Menu selected function: Menu_20
        function Menu_20Selected(app, event)
            light_value = double(app.Spinner.Value);
            global im;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1 = cputime;
                I = imadd(im, light_value);
                t2 = cputime;
                imshow(I, 'Parent', app.UIAxes2);
            end
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_21
        function Menu_21Selected(app, event)
            global im_add;
            global im;
            t1 = cputime;
            [m, n, c] = size(im);
            im_add_temp = imresize(im_add, [m, n]);
            im_temp = double(im);
            im_add_temp = double(im_add_temp);
            K = (im_temp + im_add_temp);
            K = normalize(K);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            figure;
            imshow(K);
        end

        % Menu selected function: Menu_23
        function Menu_23Selected(app, event)
            light_value = double(app.Spinner.Value);
            global im;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1 = cputime;
                I = imsubtract(im, light_value);
                t2 = cputime;
                imshow(I, 'Parent', app.UIAxes2);
            end
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_24
        function Menu_24Selected(app, event)
            global im_add;
            global im;
            t1 = cputime;
            [m, n, c] = size(im);
            im_add_temp = imresize(im_add, [m, n]);
            im_temp = double(im);
            im_add_temp = double(im_add_temp);
            K = (im_temp - im_add_temp);
            K = normalize(K);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            figure;
            imshow(K);
        end

        % Menu selected function: Menu_25
        function Menu_25Selected(app, event)
            global im_add;
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'},'选择一个图片','D:');
            if isequal(filename, 0) || isequal(pathname, 0)
                im_add = 0;
                errordlg("没有选中文件", "错误");
                return;
            else
                file = [pathname, filename];
            end
            im_add = imread(file);
            imshow(im_add, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_28
        function Menu_28Selected(app, event)
            light_value = double(app.Spinner.Value);
            global im;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1 = cputime;
                I = immultiply(im, light_value);
                t2 = cputime;
                imshow(I, 'Parent', app.UIAxes2);
            end
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_29
        function Menu_29Selected(app, event)
            global im_add;
            global im;
            t1 = cputime;
            [m, n, c] = size(im);
            im_add_temp = imresize(im_add, [m, n]);
            im_temp = double(im);
            im_add_temp = double(im_add_temp);
            K = (im_temp .* im_add_temp);
            K = normalize(K);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            figure;
            imshow(K);    
        end

        % Menu selected function: Menu_30
        function Menu_30Selected(app, event)
            light_value = double(app.Spinner.Value);
            global im;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1 = cputime;
                I = imdivide(im, light_value);
                t2 = cputime;
                imshow(I, 'Parent', app.UIAxes2);
            end
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_31
        function Menu_31Selected(app, event)
            global im_add;
            global im;
            t1 = cputime;
            [m, n, c] = size(im);
            im_add_temp = imresize(im_add, [m, n]);
            im_temp = double(im);
            im_add_temp = double(im_add_temp);
            K = (im_temp ./ im_add_temp);
            K = normalize(K);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            figure;
            imshow(K, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_33
        function Menu_33Selected(app, event)
            global im;
            global im_add;
            t1 = cputime;
            [m, n, c] = size(im);
            im_add_temp = imresize(im_add, [m, n]);
            im_temp = im2bw(im);
            im_add_temp = im2bw(im_add_temp);
            K = (im_temp & im_add_temp);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(K, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_34
        function Menu_34Selected(app, event)
            global im;
            global im_add;
            t1 = cputime;
            [m, n, c] = size(im);
            im_add_temp = imresize(im_add, [m, n]);
            im_temp = im2bw(im);
            im_add_temp = im2bw(im_add_temp);
            K = (im_temp | im_add_temp);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            figure;
            imshow(K, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_35
        function Menu_35Selected(app, event)
            global im;
            global im_add;
            t1 = cputime;
            [m, n, c] = size(im);
            im_add_temp = imresize(im_add, [m, n]);
            im_temp = im2bw(im);
            im_add_temp = im2bw(im_add_temp);
            K = (~im_temp);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(K, 'Parent', app.UIAxes2);
        end

        % Menu selected function: imhisMenu
        function imhisMenuSelected(app, event)
            global im;    
            t1 = cputime;
            imhist(im);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            title("imhist的直方图");
        end

        % Menu selected function: barMenu
        function barMenuSelected(app, event)
            global im;
            im_temp = double(im);
            [m, n, h] = size(im);
            if h == 3
               t1 = cputime;
               K = zeros(h, 256);
               for k=1:h
                   for i=1:m
                       for j=1:n
                           K(k, im_temp(i, j, k) + 1) = K(k, im_temp(i, j, k) + 1) + 1;
                       end
                   end
               end
               t2 = cputime;
               X = [0:1:255];
               hold on;
               bar(X, K(1,:),'r');
               bar(X, K(2,:), 'g');
               bar(X, K(3,:), 'b');
               app.TimeUse.Value = t2 - t1;
            else
                t1 = cputime;
                imhist(im);
                t2 = cputime;
                app.TimeUse.Value = t2 - t1;
            end
            title("bar的直方图");
        end

        % Menu selected function: stemMenu
        function stemMenuSelected(app, event)
            global im;
            im_temp = double(im);
            X = [0:1:255];
            [m, n, h] = size(im);
            if h == 3
               t1 = cputime;
               K = zeros(h, 256);
               for k=1:h
                   for i=1:m
                       for j=1:n
                           K(k, im_temp(i, j, k) + 1) = K(k, im_temp(i, j, k) + 1) + 1;
                       end
                   end
               end
               t2 = cputime;
               hold on;
               stem(X, K(1,:),'r');
               stem(X, K(2,:), 'g');
               stem(X, K(3,:), 'b');
               app.TimeUse.Value = t2 - t1;
            else
                figure;
                t1 = cputime;
                [counts, ~] = imhist(im);
                stem(X, counts);
                t2 = cputime;
                app.TimeUse.Value = t2 - t1;
            end
            title("stem的直方图");
        end

        % Menu selected function: Menu_38
        function Menu_38Selected(app, event)
            
        end

        % Menu selected function: RGBMenu
        function RGBMenuSelected(app, event)
            global im;
            [m, n, h] = size(im);
            if h == 3
                t1 = cputime;
                Ir = im(:, :, 1); % 提取红色分量
                Ig = im(:, :, 2); % 提取绿色分量
                Ib = im(:, :, 3); % 提取蓝色分量
                R = double(im(:, :, 1)) / 255;
                G = double(im(:, :, 2)) / 255;
                B = double(im(:, :, 3)) / 255;
                I1 = histeq(Ir);   %直方图均衡化函数histeq
                I2 = histeq(Ig);
                I3 = histeq(Ib);
                K = cat(3, I1, I2, I3);
                t2 = cputime;
                imshow(K, 'Parent', app.UIAxes2)
                figure;
                subplot(1,3,1)
                imhist(Ir);
                title("原始红色分量直方图");
                subplot(1,3,2)
                imhist(Ig);
                title("原始绿色分量直方图");
                subplot(1,3,3)
                imhist(Ib);
                title("原始蓝色分量直方图");
                
                figure;
                subplot(1,3,1)
                imhist(I1);
                title("R均衡化后直方图");
                subplot(1,3,2)
                imhist(I2);
                title("G均衡化后直方图");
                subplot(1,3,3)
                imhist(I3);
                title("B均衡化后直方图");
                app.TimeUse.Value = t2 - t1;
            else
                K = histeq(im);
                imshow(K, 'Parent', app.UIAxes2);
                figure;
                subplot(1,2,1);
                imhist(im);
                title("原始直方图");
                subplot(1,2,2);
                imhist(K);
                title("均衡化直方图");
            end
        end

        % Menu selected function: HSVMenu
        function HSVMenuSelected(app, event)
            global im;
            [m, n, h] = size(im);
            if h == 3
                t1 = cputime;
                HSV = rgb2hsv(im);
                H=HSV(:,:,1);
                S=HSV(:,:,2);
                V=HSV(:,:,3);
                I1 = histeq(H);   %直方图均衡化函数histeq
                I2 = histeq(S);
                I3 = histeq(V);
                HSV(:, :, 1) = I1;
                HSV(:, :, 2) = I2;
                HSV(:, :, 3) = I3;
                K = hsv2rgb(HSV);
                t2 = cputime;
                imshow(K, 'Parent', app.UIAxes2)
                figure;
                subplot(1,3,1)
                imhist(H);
                title("原始H分量直方图");
                subplot(1,3,2)
                imhist(S);
                title("原始S分量直方图");
                subplot(1,3,3)
                imhist(V);
                title("原始V分量直方图");
                
                figure;
                subplot(1,3,1)
                imhist(I1);
                title("H均衡化后直方图");
                subplot(1,3,2)
                imhist(I2);
                title("S均衡化后直方图");
                subplot(1,3,3)
                imhist(I3);
                title("V均衡化后直方图");
                app.TimeUse.Value = t2 - t1;
            else
                HSV = rgb2hsv(im);
                I = histeq(HSV);
                K = hsv2rgb(I);
                imshow(K, 'Parent', app.UIAxes2);
                figure;
                subplot(1,2,1);
                imhist(im);
                title("原始直方图");
                subplot(1,2,2);
                imhist(I);
                title("均衡化直方图");
            end
        end

        % Menu selected function: adaptMenu
        function adaptMenuSelected(app, event)
            % 采用adapthisteq进行均衡化处理
            global im;
            % 判断是彩色图还是灰度图
            [m, n, c] = size(im);
            im_temp = im;
            if c == 3
                % 彩色图
                LAB = rgb2lab(im_temp);
                L = LAB(:, :, 1) / 100;
                t1 = cputime;
                L = adapthisteq(L,'NumTiles',[8 8],'ClipLimit',0.005);
                t2 = cputime;
                LAB(:,:,1) = L*100;
                J = lab2rgb(LAB);
                app.TimeUse.Value = t2 - t1;
                imshow(J, 'Parent', app.UIAxes2);
            else
                % 灰度图
                t1 = cputime;
                J = adapthisteq(im_temp,'clipLimit',0.02,'Distribution','rayleigh');
                t2 = cputime;
                app.TimeUse.Value = t2 - t1;
                imshow(J, 'Parent', app.UIAxes2);
            end
            
        end

        % Callback function
        function AddNoiseSelected(app, event)
            
        end

        % Menu selected function: PepperNoise
        function PepperNoiseMenuSelected(app, event)
            global im;
            global im_noise;
            t1 = cputime;
            im_noise = imnoise(im, 'salt & pepper',0.02);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(im_noise, 'Parent', app.UIAxes);
        end

        % Callback function
        function AddNoiseMenuSelected(app, event)
            
        end

        % Menu selected function: GaussianNoise
        function GaussianNoiseMenuSelected(app, event)
            global im;
            global im_noise;
            t1 = cputime;
            im_noise = imnoise(im, 'gaussian');
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(im_noise, 'Parent', app.UIAxes);
        end

        % Callback function
        function Menu_45Selected(app, event)
            
        end

        % Callback function
        function Menu_46Selected(app, event)
            
        end

        % Menu selected function: Menu_42
        function Menu_42Selected(app, event)
            global im_noise;
            global size_value;
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            t1 = cputime;
            h = fspecial('gaussian',size_value);
            filterImg = imfilter(im_noise, h);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(filterImg, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_43
        function Menu_43Selected(app, event)
            global im_noise;
            global size_value;
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            t1 = cputime;
            h = fspecial('average',size_value);
            filterImg = imfilter(im_noise, h);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(filterImg, 'Parent', app.UIAxes2);
        end

        % Callback function
        function Menu_45Selected2(app, event)
            
        end

        % Menu selected function: Menu_47
        function Menu_47Selected(app, event)
            global size_value;
            global im_noise;
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            t1 = cputime;
            fp = padarray(im_noise, [floor((size_value-1)/2) floor((size_value-1)/2)], 0, 'both');
            h = fspecial('gaussian',size_value);
            filterImg = imfilter(fp, h);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(filterImg, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_48
        function Menu_48Selected(app, event)
            global size_value;
            global im_noise;
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            t1 = cputime;
            fp = padarray(im_noise, [floor((size_value-1)/2) floor((size_value-1)/2)], 'circular', 'both');
            h = fspecial('gaussian',size_value);
            filterImg = imfilter(fp, h);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(filterImg, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_49
        function Menu_49Selected(app, event)
            global size_value;
            global im_noise;
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            t1 = cputime;
            fp = padarray(im_noise, [floor((size_value-1)/2) floor((size_value-1)/2)], 'replicate', 'both');
            h = fspecial('gaussian',size_value);
            filterImg = imfilter(fp, h);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(filterImg, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_50
        function Menu_50Selected(app, event)
            global size_value;
            global im_noise;
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            t1 = cputime;
            fp = padarray(im_noise, [floor((size_value-1)/2) floor((size_value-1)/2)], 'symmetric', 'both');
            h = fspecial('gaussian',size_value);
            filterImg = imfilter(fp, h);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(filterImg, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_51
        function Menu_51Selected(app, event)
            global size_value;
            global im_noise;
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            t1 = cputime;
            fp = padarray(im_noise, [floor((size_value-1)/2) floor((size_value-1)/2)], 0, 'both');
            h = fspecial('gaussian',size_value);
            filterImg = imfilter(fp, h);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(filterImg, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_52
        function Menu_52Selected(app, event)
            global size_value;
            global im_noise;
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            t1 = cputime;
            fp = padarray(im_noise, [floor((size_value-1)/2) floor((size_value-1)/2)], 'circular', 'both');
            h = fspecial('gaussian',size_value);
            filterImg = imfilter(fp, h);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(filterImg, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_53
        function Menu_53Selected(app, event)
            global size_value;
            global im_noise;
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            t1 = cputime;
            fp = padarray(im_noise, [floor((size_value-1)/2) floor((size_value-1)/2)], 'replicate', 'both');
            h = fspecial('gaussian',size_value);
            filterImg = imfilter(fp, h);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(filterImg, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_54
        function Menu_54Selected(app, event)
            global size_value;
            global im_noise;
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            t1 = cputime;
            fp = padarray(im_noise, [floor((size_value-1)/2) floor((size_value-1)/2)], 'symmetric', 'both');
            h = fspecial('gaussian',size_value);
            filterImg = imfilter(fp, h);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(filterImg, 'Parent', app.UIAxes2);
        end

        % Callback function
        function Menu_55Selected(app, event)
            
        end

        % Menu selected function: Menu_56
        function Menu_56Selected(app, event)
            global im_noise;
            im_noise_temp = im_noise;
            t1 = cputime;
            for i = 1:10
                h = fspecial('average');
                im_noise_temp = imfilter(im_noise_temp, h);
            end
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(im_noise_temp, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_57
        function Menu_57Selected(app, event)
            global im_noise;
            im_noise_temp = im_noise;
            t1 = cputime;
            for i = 1:20
                h = fspecial('average');
                im_noise_temp = imfilter(im_noise_temp, h);
            end
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(im_noise_temp, 'Parent', app.UIAxes2);
        end

        % Callback function
        function Menu_40Selected(app, event)
            
        end

        % Menu selected function: Menu_60
        function Menu_60Selected(app, event)
            global size_value;
            global im_noise;
            [~,~,c] = size(im_noise);
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            t1 = cputime;
            fp = padarray(im_noise, [floor((size_value-1)/2) floor((size_value-1)/2)], 'symmetric', 'both');
            if c == 3
                I1 = colfilt(fp(:, :, 1),[size_value size_value],'sliding',@mean);
                I2 = colfilt(fp(:, :, 2),[size_value size_value],'sliding',@mean);
                I3 = colfilt(fp(:, :, 3),[size_value size_value],'sliding',@mean);
                g = cat(3, I1, I2, I3);
            else
                g = colfilt(fp,[size_value size_value],'sliding',@mean);
            end
            t2 = cputime;
            g8=uint8(g);
            app.TimeUse.Value = t2 - t1;
            imshow(g8, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_61
        function Menu_61Selected(app, event)
            global im_noise;
            global size_value;
            [~, ~, c] = size(im_noise);
            size_value=inputdlg('请输入模板','模板',[1 40]);
            order = inputdlg('按照从小到大选择第几个滤波器的值','滤波器输出的顺序值',[1 40]);
            domain = inputdlg('请输入滤波窗口,输入0为默认窗口ones', '滤波窗口', [1 40]);
            size_value=str2double(char(size_value));
            order = str2double(char(order));
            domain = str2num(domain{1});
            
            if c == 3
                t1 = cputime;
                if domain == 0
                    
                    I1 = ordfilt2(im_noise(:, :, 1), order, ones(size_value, size_value));
                    I2 = ordfilt2(im_noise(:, :, 2), order, ones(size_value, size_value));
                    I3 = ordfilt2(im_noise(:, :, 3), order, ones(size_value, size_value));
                    I = cat(3, I1, I2, I3);
                    imshow(I, 'Parent', app.UIAxes2);
                else
                    
                    I1 = ordfilt2(im_noise(:, :, 1), order, domain);
                    I2 = ordfilt2(im_noise(:, :, 2), order, domain);
                    I3 = ordfilt2(im_noise(:, :, 3), order, domain);
                    I = cat(3, I1, I2, I3);
                    imshow(I, 'Parent', app.UIAxes2);
                end
                t2 = cputime;
            else
                t1 = cputime;
                if domain == 0
                    I = ordfilt2(im_noise, order, ones(size_value, size_value));
                    imshow(I, 'Parent', app.UIAxes2);
                else
                    
                    I = ordfilt2(im_noise, order, domain);
                    imshow(I, 'Parent', app.UIAxes2);
                end
                 t2 = cputime;
            end
            app.TimeUse.Value = t2 - t1;
        end

        % Menu selected function: Menu_62
        function Menu_62Selected(app, event)
            global im_noise;
            global size_value;
            [~, ~, c] = size(im_noise);
            size_value=inputdlg('请输入模板','模板',[1 40]);
            size_value=str2double(char(size_value));
            if c == 3
                t1 = cputime;
                I1 = medfilt2(im_noise(:, :, 1), [size_value size_value]);
                I2 = medfilt2(im_noise(:, :, 2), [size_value size_value]);
                I3 = medfilt2(im_noise(:, :, 3), [size_value size_value]);
                I = cat(3, I1, I2, I3);
                t2 = cputime;
                imshow(I, 'Parent', app.UIAxes2);
            else
                t1 = cputime;
                I = medfilt2(im_noise, [size_value size_value]);
                imshow(I, 'Parent', app.UIAxes2);
                t2 = cputime;
            end
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
        end

        % Menu selected function: Menu_64
        function Menu_64Selected(app, event)
            global im;
            t1 = cputime;
            [h, w, c] = size(im);
            new_img = zeros(h, w, c);
            img_temp = im2double(im);
            for k = 1:c
                for i = 2:h-1   
                    for j = 2:w-1
                        new_img(i,j,k)=img_temp(i+1,j,k)+img_temp(i-1,j,k)+img_temp(i,j+1,k)+ ...
                                       img_temp(i,j-1,k)+img_temp(i-1,j-1,k)+img_temp(i-1,j+1,k)+ ...
                                       img_temp(i+1,j-1,k)+img_temp(i+1,j+1,k) - ...
                                        8*img_temp(i,j,k);
                    end
                end
            end
            img_laplace = img_temp - new_img;
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(img_laplace, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_65
        function Menu_65Selected(app, event)
            global im;
            [h, w, c] = size(im);
            
            %% 5 × 5算子
            % 先对图片进行padding，再根据Laplace公式运用matlab的向量点乘运算计算每个卷积结果。
            t1 = cputime;
            img_copy = im2double(im);
            img_temp = padarray(img_copy, [2 2], 'replicate');  % padding重复填充
            new_img = zeros(h, w, c);
            pattern = -1 * ones(5,5);
            pattern(3, 3) = 24;
            
            [h1, w1, c] = size(img_temp);
            
            for k = 1:c
                for i = 1:h1-4
                    for j = 2:w1-4
                          temp = img_temp(i:i+4, j:j+4, k);
                          laplace_img = sum(sum(pattern .* temp));
                          new_img(i, j, k) = laplace_img;
                    end
                end
            end
            laplace_img = img_copy - new_img;
            subplot(2,2,1)
            imshow(laplace_img);
            title('5×5的Laplace算子');
                        
            %% 9 × 9 算子
            
            
            img_copy = im2double(im);
            img_temp = padarray(img_copy, [4 4], 'replicate');  % padding重复填充
            new_img = zeros(h, w, c);
            pattern = -1 * ones(9,9);
            pattern(5, 5) = 80;
            
            [h1, w1, c] = size(img_temp);
            
            for k = 1:c
                for i = 1:h1-8
                    for j = 2:w1-8
                          temp = img_temp(i:i+8, j:j+8, k);
                          laplace_img = sum(sum(pattern .* temp));
                          new_img(i, j, k) = laplace_img;
                    end
                end
            end
            laplace_img = img_copy - new_img;
            subplot(2,2,2)
            imshow(laplace_img);
            title('9 × 9的Laplace算子');
            
            %% 15 × 15 算子
            
            img_copy = im2double(im);
            img_temp = padarray(img_copy, [7 7], 'replicate');  % padding重复填充
            new_img = zeros(h, w, c);
            pattern = -1 * ones(15,15);
            pattern(8, 8) = 224;
            
            [h1, w1, c] = size(img_temp);
            
            for k = 1:c
                for i = 1:h1-14
                    for j = 2:w1-14
                          temp = img_temp(i:i+14, j:j+14, k);
                          laplace_img = sum(sum(pattern .* temp));
                          new_img(i, j, k) = laplace_img;
                    end
                end
            end
            laplace_img = img_copy - new_img;
            subplot(2,2,3)
            imshow(laplace_img);
            title('15 × 15的Laplace算子');
            
            %% 25 × 25 算子
            
            img_copy = im2double(im);
            img_temp = padarray(img_copy, [12 12], 'replicate');  % padding重复填充
            new_img = zeros(h, w, c);
            pattern = -1 * ones(25,25);
            pattern(13, 13) = 624;
            
            [h1, w1, c] = size(img_temp);
            
            for k = 1:c
                for i = 1:h1-24
                    for j = 2:w1-24
                          temp = img_temp(i:i+24, j:j+24, k);
                          laplace_img = sum(sum(pattern .* temp));
                          new_img(i, j, k) = laplace_img;
                    end
                end
            end
            laplace_img = img_copy - new_img;
            subplot(2,2,4)
            imshow(laplace_img);
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            title('15 × 15的Laplace算子');
        end

        % Menu selected function: fspecialMenu
        function fspecialMenuSelected(app, event)
            global im;
            w = fspecial('laplacian',0);
            f = im2double(im);
            laplace_img1 = imfilter(f,w,0);
            laplace_img2 = imfilter(f,w,'replicate');
            laplace_img3 = imfilter(f,w,'symmetric');
            laplace_img4 = imfilter(f,w,'circular');

            laplace_img1 = f - laplace_img1;
            laplace_img2 = f - laplace_img2;
            laplace_img3 = f - laplace_img3;
            laplace_img4 = f - laplace_img4;
            subplot(2,2,1);
            imshow(laplace_img1);
            title('零填充Laplace平滑');
            
            subplot(2,2,2);
            imshow(laplace_img2);
            title('replicate Laplace平滑');
            
            subplot(2,2,3);
            imshow(laplace_img3);
            title('symmetric Laplace平滑');
            
            subplot(2,2,4);
            imshow(laplace_img4);
            title('circular Laplace平滑');
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
            global im;
            im_copy = im;
            [h, w, c] = size(im_copy);
            t1 = cputime;
            im_before = im2double(im_copy);
            im_after = im_before;
            for k = 1:c
                for i = 1:h-2
                    for j = 1:w-2
                        im_after(i, j, k) = -im_before(i, j, k) - 2*im_before(i+1, j, k) - im_before(i+2, j, k) + ...
                                                im_before(i, j+2, k) + 2*im_before(i+1, j+2, k) + im_before(i+2, j+2, k) + ...
                                            im_before(i, j, k) + 2*im_before(i, j+1, k) + im_before(i, j+2, k) - ...
                                                im_before(i+2, j, k) - 2*im_before(i+2, j+1, k) - im_before(i+2, j+2, k);
                    end
                end
            end
            im_after
            im_Prewitt = im2double(im_copy) - im_after;
            t2 = cputime;
            app.TimeUse.Value = t2 - t1;
            imshow(im_Prewitt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: GradMenu
        function GradMenuSelected(app, event)
            global im;
            im_copy = im;
            [h, w, c] = size(im_copy);
            im_before = im2double(im_copy);
            im_after = im_before;
            for k = 1:c
                for i = 1:h-1
                    for j = 1:w-1
                        im_after(i, j, k) = abs(im_before(i, j, k) - im_before(i+1, j, k)) + ...
                                            abs(im_before(i, j, k) - im_before(i, j+1, k));
                    end
                end
            end
            im_Grad = im2double(im_copy) - im_after;
            imshow(im_Grad, 'Parent', app.UIAxes2);
        end

        % Menu selected function: RobertsMenu
        function RobertsMenuSelected(app, event)
            global im;
            im_copy = im;
            [h, w, c] = size(im_copy);
            im_before = im2double(im_copy);
            im_after = im_before;
            for k = 1:c
                for i = 1:h-1
                    for j = 1:w-1
                        im_after(i, j, k) = abs(im_before(i, j, k) - im_before(i+1, j+1, k)) + ...
                                            abs(im_before(i+1, j, k) - im_before(i, j+1, k));
                    end
                end
            end
            im_Robert = im2double(im_copy) - im_after;
            imshow(im_Robert, 'Parent', app.UIAxes2);
        end

        % Menu selected function: PrewittMenu
        function PrewittMenuSelected(app, event)
            global im;
            im_copy = im;
            [h, w, c] = size(im_copy);
            im_before = im2double(im_copy);
            im_after = im_before;
            for k = 1:c
                for i = 1:h-2
                    for j = 1:w-2
                        im_after(i, j, k) = abs(-im_before(i, j, k) - im_before(i+1, j, k) - im_before(i+2, j, k) + ...
                                                im_before(i, j+2, k) + im_before(i+1, j+2, k) + im_before(i+2, j+2, k)) + ...
                                            abs(im_before(i, j, k) + im_before(i, j+1, k) + im_before(i, j+2, k) - ...
                                                im_before(i+2, j, k) - im_before(i+2, j+1, k) - im_before(i+2, j+2, k));
                    end
                end
            end
            im_Prewitt = im2double(im_copy) - im_after;
            imshow(im_Prewitt, 'Parent', app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu_read = uimenu(app.UIFigure);
            app.Menu_read.MenuSelectedFcn = createCallbackFcn(app, @Menu_readSelected, true);
            app.Menu_read.Text = '读取图片';
            app.Menu_save = uimenu(app.UIFigure);
            app.Menu_save.MenuSelectedFcn = createCallbackFcn(app, @Menu_saveSelected, true);
            app.Menu_save.Text = '保存图片';
            app.Menu_process = uimenu(app.UIFigure);
            app.Menu_process.MenuSelectedFcn = createCallbackFcn(app, @Menu_processSelected, true);
            app.Menu_process.Text = '图像处理';
            app.Menu = uimenu(app.Menu_process);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '灰度处理';
            app.Menu_2 = uimenu(app.Menu_process);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '镜像处理';
            app.Menu_9 = uimenu(app.Menu_2);
            app.Menu_9.MenuSelectedFcn = createCallbackFcn(app, @Menu_9Selected, true);
            app.Menu_9.Text = '水平镜像';
            app.Menu_10 = uimenu(app.Menu_2);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '垂直镜像';
            app.Menu_11 = uimenu(app.Menu_2);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '对角镜像';
            app.Menu_3 = uimenu(app.Menu_process);
            app.Menu_3.Text = '旋转处理';
            app.Menu_4 = uimenu(app.Menu_3);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '最邻近旋转';
            app.Menu_5 = uimenu(app.Menu_3);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '双线性旋转';
            app.Menu_6 = uimenu(app.Menu_3);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '双立方旋转';
            app.Menu_7 = uimenu(app.Menu_process);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '图像剪切';
            app.Menu_8 = uimenu(app.Menu_process);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '图像缩放';
            app.Menu_12 = uimenu(app.Menu_process);
            app.Menu_12.Text = '图像变换';
            app.Menu_13 = uimenu(app.Menu_12);
            app.Menu_13.Text = '错切变换';
            app.Menu_16 = uimenu(app.Menu_13);
            app.Menu_16.MenuSelectedFcn = createCallbackFcn(app, @Menu_16Selected, true);
            app.Menu_16.Text = '垂直错切';
            app.Menu_17 = uimenu(app.Menu_13);
            app.Menu_17.MenuSelectedFcn = createCallbackFcn(app, @Menu_17Selected, true);
            app.Menu_17.Text = '水平错切';
            app.Menu_14 = uimenu(app.Menu_12);
            app.Menu_14.MenuSelectedFcn = createCallbackFcn(app, @Menu_14Selected, true);
            app.Menu_14.Text = '平移变换';
            app.Menu_18 = uimenu(app.UIFigure);
            app.Menu_18.MenuSelectedFcn = createCallbackFcn(app, @Menu_18Selected, true);
            app.Menu_18.Text = '图像运算';
            app.Menu_25 = uimenu(app.Menu_18);
            app.Menu_25.MenuSelectedFcn = createCallbackFcn(app, @Menu_25Selected, true);
            app.Menu_25.Text = '读取图像';
            app.Menu_19 = uimenu(app.Menu_18);
            app.Menu_19.Text = '图像加法';
            app.Menu_20 = uimenu(app.Menu_19);
            app.Menu_20.MenuSelectedFcn = createCallbackFcn(app, @Menu_20Selected, true);
            app.Menu_20.Text = '图像亮度加法';
            app.Menu_21 = uimenu(app.Menu_19);
            app.Menu_21.MenuSelectedFcn = createCallbackFcn(app, @Menu_21Selected, true);
            app.Menu_21.Text = '图像相加';
            app.Menu_22 = uimenu(app.Menu_18);
            app.Menu_22.Text = '图像减法';
            app.Menu_23 = uimenu(app.Menu_22);
            app.Menu_23.MenuSelectedFcn = createCallbackFcn(app, @Menu_23Selected, true);
            app.Menu_23.Text = '图像亮度相减';
            app.Menu_24 = uimenu(app.Menu_22);
            app.Menu_24.MenuSelectedFcn = createCallbackFcn(app, @Menu_24Selected, true);
            app.Menu_24.Text = '图像相减';
            app.Menu_26 = uimenu(app.Menu_18);
            app.Menu_26.Text = '图像乘法';
            app.Menu_28 = uimenu(app.Menu_26);
            app.Menu_28.MenuSelectedFcn = createCallbackFcn(app, @Menu_28Selected, true);
            app.Menu_28.Text = '图像亮度相乘';
            app.Menu_29 = uimenu(app.Menu_26);
            app.Menu_29.MenuSelectedFcn = createCallbackFcn(app, @Menu_29Selected, true);
            app.Menu_29.Text = '图像相乘';
            app.Menu_27 = uimenu(app.Menu_18);
            app.Menu_27.Text = '图像除法';
            app.Menu_30 = uimenu(app.Menu_27);
            app.Menu_30.MenuSelectedFcn = createCallbackFcn(app, @Menu_30Selected, true);
            app.Menu_30.Text = '图像亮度相除';
            app.Menu_31 = uimenu(app.Menu_27);
            app.Menu_31.MenuSelectedFcn = createCallbackFcn(app, @Menu_31Selected, true);
            app.Menu_31.Text = '图像相除';
            app.Menu_32 = uimenu(app.Menu_18);
            app.Menu_32.Text = '逻辑运算';
            app.Menu_33 = uimenu(app.Menu_32);
            app.Menu_33.MenuSelectedFcn = createCallbackFcn(app, @Menu_33Selected, true);
            app.Menu_33.Text = '图像与';
            app.Menu_34 = uimenu(app.Menu_32);
            app.Menu_34.MenuSelectedFcn = createCallbackFcn(app, @Menu_34Selected, true);
            app.Menu_34.Text = '图像或';
            app.Menu_35 = uimenu(app.Menu_32);
            app.Menu_35.MenuSelectedFcn = createCallbackFcn(app, @Menu_35Selected, true);
            app.Menu_35.Text = '图像非';
            app.Menu_36 = uimenu(app.UIFigure);
            app.Menu_36.Text = '直方图运算';
            app.Menu_37 = uimenu(app.Menu_36);
            app.Menu_37.Text = '显示直方图';
            app.imhisMenu = uimenu(app.Menu_37);
            app.imhisMenu.MenuSelectedFcn = createCallbackFcn(app, @imhisMenuSelected, true);
            app.imhisMenu.Text = 'imhis';
            app.barMenu = uimenu(app.Menu_37);
            app.barMenu.MenuSelectedFcn = createCallbackFcn(app, @barMenuSelected, true);
            app.barMenu.Text = 'bar';
            app.stemMenu = uimenu(app.Menu_37);
            app.stemMenu.MenuSelectedFcn = createCallbackFcn(app, @stemMenuSelected, true);
            app.stemMenu.Text = 'stem';
            app.Menu_38 = uimenu(app.Menu_36);
            app.Menu_38.MenuSelectedFcn = createCallbackFcn(app, @Menu_38Selected, true);
            app.Menu_38.Text = '均衡化';
            app.RGBMenu = uimenu(app.Menu_38);
            app.RGBMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBMenuSelected, true);
            app.RGBMenu.Text = 'RGB均衡';
            app.HSVMenu = uimenu(app.Menu_38);
            app.HSVMenu.MenuSelectedFcn = createCallbackFcn(app, @HSVMenuSelected, true);
            app.HSVMenu.Text = 'HSV均衡';
            app.adaptMenu = uimenu(app.Menu_38);
            app.adaptMenu.MenuSelectedFcn = createCallbackFcn(app, @adaptMenuSelected, true);
            app.adaptMenu.Text = 'adapt均衡 ';
            app.Menu_40 = uimenu(app.UIFigure);
            app.Menu_40.Text = '图像平滑';
            app.AddNoise = uimenu(app.Menu_40);
            app.AddNoise.Text = '图像加噪';
            app.PepperNoise = uimenu(app.AddNoise);
            app.PepperNoise.MenuSelectedFcn = createCallbackFcn(app, @PepperNoiseMenuSelected, true);
            app.PepperNoise.Text = '椒盐噪声';
            app.GaussianNoise = uimenu(app.AddNoise);
            app.GaussianNoise.MenuSelectedFcn = createCallbackFcn(app, @GaussianNoiseMenuSelected, true);
            app.GaussianNoise.Text = '高斯噪声';
            app.Menu_41 = uimenu(app.Menu_40);
            app.Menu_41.Text = '低通滤波';
            app.Menu_42 = uimenu(app.Menu_41);
            app.Menu_42.MenuSelectedFcn = createCallbackFcn(app, @Menu_42Selected, true);
            app.Menu_42.Text = '高斯滤波';
            app.Menu_43 = uimenu(app.Menu_41);
            app.Menu_43.MenuSelectedFcn = createCallbackFcn(app, @Menu_43Selected, true);
            app.Menu_43.Text = '均值滤波';
            app.PaddingMenu = uimenu(app.Menu_40);
            app.PaddingMenu.Text = 'Padding';
            app.Menu_45 = uimenu(app.PaddingMenu);
            app.Menu_45.Text = '高斯滤波';
            app.Menu_47 = uimenu(app.Menu_45);
            app.Menu_47.MenuSelectedFcn = createCallbackFcn(app, @Menu_47Selected, true);
            app.Menu_47.Text = '零填充';
            app.Menu_48 = uimenu(app.Menu_45);
            app.Menu_48.MenuSelectedFcn = createCallbackFcn(app, @Menu_48Selected, true);
            app.Menu_48.Text = '重复填充';
            app.Menu_49 = uimenu(app.Menu_45);
            app.Menu_49.MenuSelectedFcn = createCallbackFcn(app, @Menu_49Selected, true);
            app.Menu_49.Text = '边界填充';
            app.Menu_50 = uimenu(app.Menu_45);
            app.Menu_50.MenuSelectedFcn = createCallbackFcn(app, @Menu_50Selected, true);
            app.Menu_50.Text = '镜面填充';
            app.Menu_46 = uimenu(app.PaddingMenu);
            app.Menu_46.Text = '均值滤波';
            app.Menu_51 = uimenu(app.Menu_46);
            app.Menu_51.MenuSelectedFcn = createCallbackFcn(app, @Menu_51Selected, true);
            app.Menu_51.Text = '零填充';
            app.Menu_52 = uimenu(app.Menu_46);
            app.Menu_52.MenuSelectedFcn = createCallbackFcn(app, @Menu_52Selected, true);
            app.Menu_52.Text = '重复填充';
            app.Menu_53 = uimenu(app.Menu_46);
            app.Menu_53.MenuSelectedFcn = createCallbackFcn(app, @Menu_53Selected, true);
            app.Menu_53.Text = '边界填充';
            app.Menu_54 = uimenu(app.Menu_46);
            app.Menu_54.MenuSelectedFcn = createCallbackFcn(app, @Menu_54Selected, true);
            app.Menu_54.Text = '镜面填充';
            app.Menu_55 = uimenu(app.Menu_40);
            app.Menu_55.Text = '循环滤波';
            app.Menu_56 = uimenu(app.Menu_55);
            app.Menu_56.MenuSelectedFcn = createCallbackFcn(app, @Menu_56Selected, true);
            app.Menu_56.Text = '10次';
            app.Menu_57 = uimenu(app.Menu_55);
            app.Menu_57.MenuSelectedFcn = createCallbackFcn(app, @Menu_57Selected, true);
            app.Menu_57.Text = '20次';
            app.Menu_58 = uimenu(app.UIFigure);
            app.Menu_58.Text = '图像锐化';
            app.Menu_60 = uimenu(app.Menu_58);
            app.Menu_60.MenuSelectedFcn = createCallbackFcn(app, @Menu_60Selected, true);
            app.Menu_60.Text = '非线性滤波';
            app.Menu_61 = uimenu(app.Menu_58);
            app.Menu_61.MenuSelectedFcn = createCallbackFcn(app, @Menu_61Selected, true);
            app.Menu_61.Text = '顺序滤波';
            app.Menu_62 = uimenu(app.Menu_58);
            app.Menu_62.MenuSelectedFcn = createCallbackFcn(app, @Menu_62Selected, true);
            app.Menu_62.Text = '中值滤波';
            app.Menu_63 = uimenu(app.Menu_58);
            app.Menu_63.Text = '锐化滤波';
            app.LaplaceMenu = uimenu(app.Menu_63);
            app.LaplaceMenu.Text = 'Laplace';
            app.Menu_64 = uimenu(app.LaplaceMenu);
            app.Menu_64.MenuSelectedFcn = createCallbackFcn(app, @Menu_64Selected, true);
            app.Menu_64.Text = '3×3';
            app.Menu_65 = uimenu(app.LaplaceMenu);
            app.Menu_65.MenuSelectedFcn = createCallbackFcn(app, @Menu_65Selected, true);
            app.Menu_65.Text = '多次对比';
            app.fspecialMenu = uimenu(app.LaplaceMenu);
            app.fspecialMenu.MenuSelectedFcn = createCallbackFcn(app, @fspecialMenuSelected, true);
            app.fspecialMenu.Text = 'fspecial';
            app.SobelMenu = uimenu(app.Menu_63);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel';
            app.PrewittMenu = uimenu(app.Menu_63);
            app.PrewittMenu.MenuSelectedFcn = createCallbackFcn(app, @PrewittMenuSelected, true);
            app.PrewittMenu.Text = 'Prewitt';
            app.RobertsMenu = uimenu(app.Menu_63);
            app.RobertsMenu.MenuSelectedFcn = createCallbackFcn(app, @RobertsMenuSelected, true);
            app.RobertsMenu.Text = 'Roberts';
            app.GradMenu = uimenu(app.Menu_63);
            app.GradMenu.MenuSelectedFcn = createCallbackFcn(app, @GradMenuSelected, true);
            app.GradMenu.Text = 'Grad';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '处理';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '清除';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '耗费时间';
            app.TimeUse = uieditfield(app.UIFigure, 'numeric');
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Label_angle_size = uilabel(app.UIFigure);
            app.Label_angle_size.Text = '角度';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EX2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end