classdef EX2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Menu_read     matlab.ui.container.Menu
        Menu_save     matlab.ui.container.Menu
        Menu_process  matlab.ui.container.Menu
        Menu          matlab.ui.container.Menu
        Menu2         matlab.ui.container.Menu
        UIAxes        matlab.ui.control.UIAxes
        UIAxes2       matlab.ui.control.UIAxes
        Label         matlab.ui.control.Label
        Label_2       matlab.ui.control.Label
        Button        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global im;
        end

        % Menu selected function: Menu_read
        function Menu_readSelected(app, event)
            global im;
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'},'选择一个图片','D:');
            
            if isequal(filename, 0) || isequal(pathname, 0)
                im = 0;
                errordlg("没有选中文件", "错误");
                return;
            else
                file = [pathname, filename];
            end
            im = imread(file);
            imshow(im, 'Parent', app.UIAxes);
        end

        % Menu selected function: Menu_process
        function Menu_processSelected(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
        end

        % Menu selected function: Menu_save
        function Menu_saveSelected(app, event)
            [filename,pathname] = uiputfile({'*.jpg','JPEG(*.jpg)';...
                                 '*.bmp','Bitmap(*.bmp)';...
                                 '*.gif','GIF(*.gif)';...
                                 '*.*',  'All Files (*.*)'},...
                                 'Save Picture','Untitled');
             if pathname == 0
                 errordlg("没有保存路径！", "错误");
                 return;
             else
                 h = getframe(app.UIAxes2);
                 imwrite(h.cdata, [pathname, filename]);
             end
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            global im;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                gray_img = rgb2gray(im);
                imshow(gray_img, 'Parent', app.UIAxes2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu_read = uimenu(app.UIFigure);
            app.Menu_read.MenuSelectedFcn = createCallbackFcn(app, @Menu_readSelected, true);
            app.Menu_read.Text = '读取图片';
            app.Menu_save = uimenu(app.UIFigure);
            app.Menu_save.MenuSelectedFcn = createCallbackFcn(app, @Menu_saveSelected, true);
            app.Menu_save.Text = '保存图片';
            app.Menu_process = uimenu(app.UIFigure);
            app.Menu_process.MenuSelectedFcn = createCallbackFcn(app, @Menu_processSelected, true);
            app.Menu_process.Text = '图像处理';
            app.Menu = uimenu(app.Menu_process);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '灰度处理';
            app.Menu2 = uimenu(app.Menu_process);
            app.Menu2.Text = 'Menu2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '灰度处理';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '清除';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EX2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end