classdef Ex1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        Max               matlab.ui.control.Label
        Min               matlab.ui.control.Label
        max               matlab.ui.control.Label
        min               matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
        Button            matlab.ui.control.Button
        fxEditFieldLabel  matlab.ui.control.Label
        fxEditField       matlab.ui.control.EditField
        Label             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            y = app.fxEditField.Value;
            name = string(y);
            %y = string(y);
            x = 0:0.1:2*pi;
            y = eval(y);
            plot(app.UIAxes,y,'--b');
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            title(app.UIAxes, name);
            app.max.Text = string(max(y));
            app.min.Text = string(min(y));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {174, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Max = uilabel(app.LeftPanel);
            app.Max.Text = '函数的最大值';
            app.Min = uilabel(app.LeftPanel);
            app.Min.Text = '函数的最小值';
            app.max = uilabel(app.LeftPanel);
            app.max.Text = '';
            app.min = uilabel(app.LeftPanel);
            app.min.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '生成';
            app.fxEditFieldLabel = uilabel(app.RightPanel);
            app.fxEditFieldLabel.Text = 'f(x)=';
            app.fxEditField = uieditfield(app.RightPanel, 'text');
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '函数图像生成器';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ex1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end