classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        UIAxes      matlab.ui.control.UIAxes
        Button      matlab.ui.control.Button
        RightPanel  matlab.ui.container.Panel
        Label       matlab.ui.control.Label
        DropDown    matlab.ui.control.DropDown
        UIAxes2     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = false;
            app.UIAxes2.Visible = false;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {402, 402};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {367, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global im;
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'},'选择一个图片','D:');
            
            if isequal(filename, 0) || isequal(pathname, 0)
                im = 0;
                errordlg("没有选中文件", "错误");
                return;
            else
                file = [pathname, filename];
            end
            im = imread(file);
            imshow(im, 'Parent', app.UIAxes);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            global im;
            if value == "RGB"
                im_temp = double(im);
                [m, n, h] = size(im);
                if h == 3
                    K = zeros(h, 256);
                    for k=1:h
                        for i=1:m
                            for j=1:n
                                K(k, im_temp(i, j, k) + 1) = K(k, im_temp(i, j, k) + 1) + 1;
                            end
                        end
                    end
                    app.UIAxes2.NextPlot = 'add';
                    app.UIAxes2.Visible = true;
                    app.UIAxes2.XGrid = 'on';
                    app.UIAxes2.YGrid = 'on';
                    bar(app.UIAxes2, K(1,:), 'r');
                    bar(app.UIAxes2, K(2,:), 'g');
                    bar(app.UIAxes2, K(3,:), 'b');
                    app.UIAxes2.XLim = [0 255];
                    
                else
                    imhist(im);
                end
            end
            if value == "R"
                im_temp = double(im);
                [m, n, h] = size(im);
                if h == 3
                    K = zeros(h, 256);
                    for k=1:h
                        for i=1:m
                            for j=1:n
                                K(k, im_temp(i, j, k) + 1) = K(k, im_temp(i, j, k) + 1) + 1;
                            end
                        end
                    end
                    app.UIAxes2.NextPlot = 'replacechildren';
                    app.UIAxes2.Visible = true;
                    app.UIAxes2.XGrid = 'on';
                    app.UIAxes2.YGrid = 'on';
                    bar(app.UIAxes2, K(1,:), 'r');
                    app.UIAxes2.XLim = [0 255];
                else
                    imhist(im);
                end
            end
            if value == "G"
                
                im_temp = double(im);
                [m, n, h] = size(im);
                if h == 3
                    K = zeros(h, 256);
                    for k=1:h
                        for i=1:m
                            for j=1:n
                                K(k, im_temp(i, j, k) + 1) = K(k, im_temp(i, j, k) + 1) + 1;
                            end
                        end
                    end
                    app.UIAxes2.NextPlot = 'replacechildren';
                    app.UIAxes2.Visible = true;
                    app.UIAxes2.XGrid = 'on';
                    app.UIAxes2.YGrid = 'on';
                    bar(app.UIAxes2, K(2,:), 'g');
                    app.UIAxes2.XLim = [0 255];
                else
                    imhist(im);
                end
            end
            if value == "B"
                im_temp = double(im);
                [m, n, h] = size(im);
                if h == 3
                    K = zeros(h, 256);
                    for k=1:h
                        for i=1:m
                            for j=1:n
                                K(k, im_temp(i, j, k) + 1) = K(k, im_temp(i, j, k) + 1) + 1;
                            end
                        end
                    end
                    app.UIAxes2.NextPlot = 'replacechildren';
                    app.UIAxes2.Visible = true;
                    app.UIAxes2.XGrid = 'on';
                    app.UIAxes2.YGrid = 'on';
                    bar(app.UIAxes2, K(3,:), 'b');
                    app.UIAxes2.XLim = [0 255];
                else
                    imhist(im);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入图片';
            app.RightPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '通道';
            app.DropDown = uidropdown(app.RightPanel);
            app.DropDown.Items = {'RGB', 'R', 'G', 'B'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'RGB';
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end