classdef EX2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu_read           matlab.ui.container.Menu
        Menu_save           matlab.ui.container.Menu
        Menu_process        matlab.ui.container.Menu
        Menu                matlab.ui.container.Menu
        Menu_2              matlab.ui.container.Menu
        Menu_9              matlab.ui.container.Menu
        Menu_10             matlab.ui.container.Menu
        Menu_11             matlab.ui.container.Menu
        Menu_3              matlab.ui.container.Menu
        Menu_4              matlab.ui.container.Menu
        Menu_5              matlab.ui.container.Menu
        Menu_6              matlab.ui.container.Menu
        Menu_7              matlab.ui.container.Menu
        Menu_8              matlab.ui.container.Menu
        Menu_12             matlab.ui.container.Menu
        Menu_13             matlab.ui.container.Menu
        Menu_16             matlab.ui.container.Menu
        Menu_17             matlab.ui.container.Menu
        Menu_14             matlab.ui.container.Menu
        UIAxes              matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        Label               matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Button              matlab.ui.control.Button
        Label_3             matlab.ui.control.Label
        TimeUse             matlab.ui.control.NumericEditField
        Spinner             matlab.ui.control.Spinner
        Label_angle_size    matlab.ui.control.Label
        Spinner_2           matlab.ui.control.Spinner
        Label_angle_size_2  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global im;
            app.Spinner.Limits = [-360, 360];
            app.Label_angle_size.Text = "处理";
        end

        % Menu selected function: Menu_read
        function Menu_readSelected(app, event)
            global im;
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'},'选择一个图片','D:');
            
            if isequal(filename, 0) || isequal(pathname, 0)
                im = 0;
                errordlg("没有选中文件", "错误");
                return;
            else
                file = [pathname, filename];
            end
            im = imread(file);
            imshow(im, 'Parent', app.UIAxes);
        end

        % Menu selected function: Menu_process
        function Menu_processSelected(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.Label_2.Text = '处理';
        end

        % Menu selected function: Menu_save
        function Menu_saveSelected(app, event)
            [filename,pathname] = uiputfile({'*.jpg','JPEG(*.jpg)';...
                                 '*.bmp','Bitmap(*.bmp)';...
                                 '*.gif','GIF(*.gif)';...
                                 '*.*',  'All Files (*.*)'},...
                                 'Save Picture','Untitled');
             if pathname == 0
                 errordlg("没有保存路径！", "错误");
                 return;
             else
                 h = getframe(app.UIAxes2);
                 imwrite(h.cdata, [pathname, filename]);
             end
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                gray_img = rgb2gray(im);
                imshow(gray_img, 'Parent', app.UIAxes2);
                t2=cputime;
            end
            app.Label_2.Text = '灰度处理';
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            
        end

        % Menu selected function: Menu_9
        function Menu_9Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                [Height, Width] = size(im);
                T1 = affine2d([-1 0 0; 0 1 0;Width 0 1]);
                A1 = imwarp(im, T1);
                imshow(A1,'Parent', app.UIAxes2);
                t2=cputime;
            end
            app.Label_2.Text = '水平镜像';
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                [Height, Width] = size(im);
                T2 = affine2d([1 0 0;0 -1 0;0 Height 1]);
                A2 = imwarp(im, T2);
                imshow(A2,'Parent', app.UIAxes2);
                t2=cputime;
            end
            app.Label_2.Text = '垂直镜像';
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                [Height, Width] = size(im);
                T3 = affine2d([-1 0 0;0 -1 0;Width Height 1]);
                A3 = imwarp(im, T3);
                imshow(A3,'Parent', app.UIAxes2);
                t2=cputime;
            end
            app.Label_2.Text = '对角镜像';
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            app.Spinner.Visible = true;
            app.Label_angle_size.Visible = true;
            app.Label_angle_size.Text = "角度";
            angle = double(app.Spinner.Value);
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                J = imrotate(im, angle, 'nearest', 'crop');
                t2 = cputime;
                imshow(J,'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "最邻近旋转" + num2str(angle,3) + "度";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            app.Label_angle_size.Text = "角度";
            angle = double(app.Spinner.Value);
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                J = imrotate(im, angle, 'bilinear', 'crop');
                t2 = cputime;
                imshow(J,'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "双线性旋转" + num2str(angle,3) + "度";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            app.Label_angle_size.Text = "角度";
            angle = double(app.Spinner.Value);
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                J = imrotate(im, angle, 'bicubic', 'crop');
                t2 = cputime;
                imshow(J,'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "双立方旋转" + num2str(angle,3) + "度";
            t=t2-t1;
            app.TimeUse.Value = t;      
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1=cputime;
                [J,~] = imcrop(im);
                t2 = cputime;
                imshow(J,'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "剪切图像";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                app.Label_angle_size.Text = "大小";
                size_pic = double(app.Spinner.Value);
                if size_pic == 0
                    size_pic = 1;
                end
                tform = affine2d([size_pic 0 0; 0 size_pic 0; 0 0 1]);
                t1=cputime;
                J = imwarp(im,tform,'OutputView',imref2d(size(im)));%图像放大缩小
                t2 = cputime;
                imshow(J, 'Parent', app.UIAxes2);
            end
            app.UIAxes.Visible = true;
            app.UIAxes2.Visible = true;
            app.Label_2.Text = "缩放图像";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
         
        end

        % Callback function
        function Menu_13Selected(app, event)
            
        end

        % Menu selected function: Menu_16
        function Menu_16Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            app.Label_angle_size.Text = "角度";
            angle = double(app.Spinner.Value);
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                tform = affine2d([1 tan(angle) 0; 0 1 0; 0 0 1]);
                t1 = cputime;
                J = imwarp(im,tform);
                t2 = cputime;
                imshow(J, 'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "垂直错切图像";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_17
        function Menu_17Selected(app, event)
            global im;
            app.Label_angle_size_2.Visible = false;
            app.Spinner_2.Visible = false;
            app.Label_angle_size.Text = "角度";
            angle = double(app.Spinner.Value);
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                t1 = cputime;
                tform = affine2d([1 0 0; tan(angle) 1 0; 0 0 1]);
                J = imwarp(im,tform);
                t2 = cputime;
                imshow(J, 'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "水平错切图像";
            t=t2-t1;
            app.TimeUse.Value = t;
        end

        % Menu selected function: Menu_14
        function Menu_14Selected(app, event)
            global im;
            app.Label_angle_size.Text = "X";
            app.Label_angle_size_2.Text = "Y";
            app.Label_angle_size_2.Visible = true;
            app.Spinner_2.Visible = true;
            app.UIAxes2.Visible = true;
            Tx = double(app.Spinner.Value);
            Ty = double(app.Spinner.Value);
            im_ref = imref2d(size(im));
            % 限制坐标轴不动
            im_translated_ref = im_ref;
            if im == 0
                errordlg("没有选中文件", "错误")
                return;
            else
                tform =affine2d([1 0 0 ; 0 1 0 ; Tx Ty 1 ]);
                t1 = cputime;
                J = imwarp(im,tform,'OutputView',im_translated_ref);%图像平移
                t2 = cputime;
                imshow(J, 'Parent', app.UIAxes2);
            end
            app.Label_2.Text = "平移变换图像";
            t=t2-t1;
            app.TimeUse.Value = t;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu_read = uimenu(app.UIFigure);
            app.Menu_read.MenuSelectedFcn = createCallbackFcn(app, @Menu_readSelected, true);
            app.Menu_read.Text = '读取图片';
            app.Menu_save = uimenu(app.UIFigure);
            app.Menu_save.MenuSelectedFcn = createCallbackFcn(app, @Menu_saveSelected, true);
            app.Menu_save.Text = '保存图片';
            app.Menu_process = uimenu(app.UIFigure);
            app.Menu_process.MenuSelectedFcn = createCallbackFcn(app, @Menu_processSelected, true);
            app.Menu_process.Text = '图像处理';
            app.Menu = uimenu(app.Menu_process);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '灰度处理';
            app.Menu_2 = uimenu(app.Menu_process);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '镜像处理';
            app.Menu_9 = uimenu(app.Menu_2);
            app.Menu_9.MenuSelectedFcn = createCallbackFcn(app, @Menu_9Selected, true);
            app.Menu_9.Text = '水平镜像';
            app.Menu_10 = uimenu(app.Menu_2);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '垂直镜像';
            app.Menu_11 = uimenu(app.Menu_2);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '对角镜像';
            app.Menu_3 = uimenu(app.Menu_process);
            app.Menu_3.Text = '旋转处理';
            app.Menu_4 = uimenu(app.Menu_3);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '最邻近旋转';
            app.Menu_5 = uimenu(app.Menu_3);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '双线性旋转';
            app.Menu_6 = uimenu(app.Menu_3);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '双立方旋转';
            app.Menu_7 = uimenu(app.Menu_process);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '图像剪切';
            app.Menu_8 = uimenu(app.Menu_process);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '图像缩放';
            app.Menu_12 = uimenu(app.Menu_process);
            app.Menu_12.Text = '图像变换';
            app.Menu_13 = uimenu(app.Menu_12);
            app.Menu_13.Text = '错切变换';
            app.Menu_16 = uimenu(app.Menu_13);
            app.Menu_16.MenuSelectedFcn = createCallbackFcn(app, @Menu_16Selected, true);
            app.Menu_16.Text = '垂直错切';
            app.Menu_17 = uimenu(app.Menu_13);
            app.Menu_17.MenuSelectedFcn = createCallbackFcn(app, @Menu_17Selected, true);
            app.Menu_17.Text = '水平错切';
            app.Menu_14 = uimenu(app.Menu_12);
            app.Menu_14.MenuSelectedFcn = createCallbackFcn(app, @Menu_14Selected, true);
            app.Menu_14.Text = '平移变换';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '处理';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '清除';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '耗费时间';
            app.TimeUse = uieditfield(app.UIFigure, 'numeric');
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Label_angle_size = uilabel(app.UIFigure);
            app.Label_angle_size.Text = '角度';
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.Visible = 'off';
            app.Label_angle_size_2 = uilabel(app.UIFigure);
            app.Label_angle_size_2.Visible = 'off';
            app.Label_angle_size_2.Text = 'Y';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EX2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end